classdef demoGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        InitRestartButton            matlab.ui.control.Button
        loadeddomainLabel            matlab.ui.control.Label
        nogenerationsEditFieldLabel  matlab.ui.control.Label
        nogenerationsEditField       matlab.ui.control.NumericEditField
        domainDropDownLabel          matlab.ui.control.Label
        domainDropDown               matlab.ui.control.DropDown
        featurexaxisDropDownLabel    matlab.ui.control.Label
        featurexaxisDropDown         matlab.ui.control.DropDown
        featureyaxisDropDownLabel    matlab.ui.control.Label
        featureyaxisDropDown         matlab.ui.control.DropDown
        RedrawButton                 matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        GenotypicSimilarityButton    matlab.ui.control.Button
        GenotypicPrototypesButton    matlab.ui.control.Button
        PhenotypicPrototypesButton   matlab.ui.control.Button
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        p % Algorithm configuration
        d % Domain configuration
        map % Current map object
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('.'));
            DOMAIN = 'dropwave'; %npoly dropwave shubert4
            rmpath(genpath('domain')); addpath(['domain/' DOMAIN]);
            
            ALGORITHM = 'vElites';
            rmpath('mapElites'); rmpath('vElites'); addpath(ALGORITHM);
            % Load configuration of domain and V-Elites
            app.d = domain;
            app.p = defaultParamSet(4);
            app.nogenerationsEditField.Value = app.p.nGens;
            app.loadeddomainLabel.Text = ['DOMAIN: ' DOMAIN];
            
            app.featurexaxisDropDown.Items = app.d.featureLabels;
            app.featureyaxisDropDown.Items = app.d.featureLabels;   
            app.featurexaxisDropDown.Value = app.featurexaxisDropDown.Items{1};            
            app.featureyaxisDropDown.Value = app.featureyaxisDropDown.Items{2};
            
            app.UIAxes.Title.String = 'Phenotypic Map';
            app.UIAxes2.Title.String = 'Genotypic Similarity';
            
        end

        % Button pushed function: InitRestartButton
        function InitRestartButtonPushed(app, event)
            
            sobSequence         = scramble(sobolset(app.d.dof,'Skip',1e3),'MatousekAffineOwen');
            sobPoint            = 1;
            initSamples         = range(app.d.ranges).*sobSequence(sobPoint:(sobPoint+app.p.numInitSamples)-1,:)+app.d.ranges(1);
            [fitness, values]   = app.d.fitfun(initSamples);
            obsMap              = createMap(app.d.dof, app.p.maxBins, app.p.competeDistance, app.p.infReplacement);
            [replaced, replacement, features] = nicheCompete(initSamples, fitness, values, obsMap, app.d);
            obsMap = updateMap(replaced,replacement,obsMap,fitness,initSamples,features);
            
            [app.map, ~, ~, ~, allMaps] = vElites(app.d.fitfun,obsMap,app.p,app.d);
            
            % Show data
            viewMap(app.map,app.d,app.UIAxes);% title('Fitness'); caxis(d.fitnessRange);            
        end

        % Value changed function: nogenerationsEditField
        function nogenerationsEditFieldValueChanged(app, event)
            value = app.nogenerationsEditField.Value;
            app.p.nGens = value;
            app.nogenerationsEditField.Value = value;
        end

        % Value changed function: domainDropDown
        function domainDropDownValueChanged(app, event)
            value = app.domainDropDown.Value;
            addpath(genpath('.'));
            DOMAIN = value;
            rmpath(genpath('domain')); addpath(['domain/' DOMAIN]);
            ALGORITHM = 'vElites';
            rmpath('mapElites'); rmpath('vElites'); addpath(ALGORITHM);
            app.d = domain;
            app.loadeddomainLabel.Text = ['DOMAIN: ' DOMAIN];
            app.featurexaxisDropDown.Items = app.d.featureLabels;
            app.featureyaxisDropDown.Items = app.d.featureLabels;  
            app.featurexaxisDropDown.Value = app.featurexaxisDropDown.Items{1};            
            app.featureyaxisDropDown.Value = app.featureyaxisDropDown.Items{2};            
        end

        % Value changed function: featurexaxisDropDown
        function featurexaxisDropDownValueChanged(app, event)
            value = app.featurexaxisDropDown.Value;
            col = find(strcmp(value,app.d.featureLabels));
            app.d.selectedFeatures(1) = col;
        end

        % Value changed function: featureyaxisDropDown
        function featureyaxisDropDownValueChanged(app, event)
            value = app.featureyaxisDropDown.Value;
            col = find(strcmp(value,app.d.featureLabels));
            app.d.selectedFeatures(2) = col;            
        end

        % Button pushed function: RedrawButton
        function RedrawButtonPushed(app, event)
            viewMap(app.map,app.d,app.UIAxes);% title('Fitness'); caxis(d.fitnessRange);
        end

        % Button pushed function: GenotypicSimilarityButton
        function GenotypicSimilarityButtonPushed(app, event)
            viewClasses(app.map.genes,[],app.UIAxes2);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.InitRestartButton = uibutton(app.UIFigure, 'push');
            app.InitRestartButton.ButtonPushedFcn = createCallbackFcn(app, @InitRestartButtonPushed, true);
            app.InitRestartButton.Text = 'Init/Restart';
            app.loadeddomainLabel = uilabel(app.UIFigure);
            app.loadeddomainLabel.Text = 'loaded domain: ';
            app.nogenerationsEditFieldLabel = uilabel(app.UIFigure);
            app.nogenerationsEditFieldLabel.Text = 'no. generations';
            app.nogenerationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.nogenerationsEditField.Limits = [1 10000];
            app.nogenerationsEditField.RoundFractionalValues = 'on';
            app.nogenerationsEditField.ValueChangedFcn = createCallbackFcn(app, @nogenerationsEditFieldValueChanged, true);
            app.nogenerationsEditField.Value = 100;
            app.domainDropDownLabel = uilabel(app.UIFigure);
            app.domainDropDownLabel.Text = 'domain';
            app.domainDropDown = uidropdown(app.UIFigure);
            app.domainDropDown.Items = {'dropwave', 'shubert4', 'npoly'};
            app.domainDropDown.ValueChangedFcn = createCallbackFcn(app, @domainDropDownValueChanged, true);
            app.domainDropDown.Value = 'dropwave';
            app.featurexaxisDropDownLabel = uilabel(app.UIFigure);
            app.featurexaxisDropDownLabel.Text = 'feature x axis';
            app.featurexaxisDropDown = uidropdown(app.UIFigure);
            app.featurexaxisDropDown.Items = {'default'};
            app.featurexaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featurexaxisDropDownValueChanged, true);
            app.featurexaxisDropDown.Value = 'default';
            app.featureyaxisDropDownLabel = uilabel(app.UIFigure);
            app.featureyaxisDropDownLabel.Text = 'feature y axis';
            app.featureyaxisDropDown = uidropdown(app.UIFigure);
            app.featureyaxisDropDown.Items = {'default'};
            app.featureyaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featureyaxisDropDownValueChanged, true);
            app.featureyaxisDropDown.Value = 'default';
            app.RedrawButton = uibutton(app.UIFigure, 'push');
            app.RedrawButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawButtonPushed, true);
            app.RedrawButton.Text = 'Redraw';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.GenotypicSimilarityButton = uibutton(app.UIFigure, 'push');
            app.GenotypicSimilarityButton.ButtonPushedFcn = createCallbackFcn(app, @GenotypicSimilarityButtonPushed, true);
            app.GenotypicSimilarityButton.Text = 'Genotypic Similarity';
            app.GenotypicPrototypesButton = uibutton(app.UIFigure, 'push');
            app.GenotypicPrototypesButton.Text = 'Genotypic Prototypes';
            app.PhenotypicPrototypesButton = uibutton(app.UIFigure, 'push');
            app.PhenotypicPrototypesButton.Text = 'Phenotypic Prototypes';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = demoGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end