classdef demoGUItabs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        DomainTab                    matlab.ui.container.Tab
        UIAxes3                      matlab.ui.control.UIAxes
        DomainConfigurationPanel     matlab.ui.container.Panel
        domainDropDownLabel          matlab.ui.control.Label
        domainDropDown               matlab.ui.control.DropDown
        loadeddomainLabel            matlab.ui.control.Label
        TrainingTab                  matlab.ui.container.Tab
        ModelConfigurationPanel      matlab.ui.container.Panel
        IlluminationTab              matlab.ui.container.Tab
        QDConfigurationPanel         matlab.ui.container.Panel
        ArchiveDropDownLabel         matlab.ui.control.Label
        ArchiveDropDown              matlab.ui.control.DropDown
        nogenerationsEditFieldLabel  matlab.ui.control.Label
        nogenerationsEditField       matlab.ui.control.NumericEditField
        featurexaxisDropDownLabel    matlab.ui.control.Label
        featurexaxisDropDown         matlab.ui.control.DropDown
        featureyaxisDropDownLabel    matlab.ui.control.Label
        featureyaxisDropDown         matlab.ui.control.DropDown
        UserSelectionPressureSliderLabel  matlab.ui.control.Label
        UserSelectionPressureSlider  matlab.ui.control.Slider
        QDcommandsPanel              matlab.ui.container.Panel
        InitRestartButton            matlab.ui.control.Button
        RedrawButton                 matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes3_2                    matlab.ui.control.UIAxes
        IterationListBoxLabel        matlab.ui.control.Label
        IterationListBox             matlab.ui.control.ListBox
        AnalysisTab                  matlab.ui.container.Tab
        AnalysisPanel                matlab.ui.container.Panel
        GeneticPrototypingButton     matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes5_3                    matlab.ui.control.UIAxes
        SelectionTab                 matlab.ui.container.Tab
        UserSelectionPanel           matlab.ui.container.Panel
        AddSelectionButton           matlab.ui.control.Button
        RemoveButton                 matlab.ui.control.Button
        ShowUDHMButton               matlab.ui.control.Button
        UIAxes6_2                    matlab.ui.control.UIAxes
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes5_2                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        p % Algorithm configuration
        d % Domain configuration
        map % Current map object
        classification % Classification
        selectedPrototypes % Keep selection saved here
        constraints % User constraints
        iteration
    end
    
    methods (Access = private)
        
        function results = setDomain(app, DOMAIN)
            % Load domain
            rmpath(genpath('domain')); addpath(['domain/' DOMAIN]);
            app.d = domain;
            app.loadeddomainLabel.Text = ['DOMAIN: ' DOMAIN];
            % Set features to GUI
            app.featurexaxisDropDown.Items = app.d.featureLabels;
            app.featureyaxisDropDown.Items = app.d.featureLabels;  
            app.featurexaxisDropDown.Value = app.featurexaxisDropDown.Items{1};            
            app.featureyaxisDropDown.Value = app.featureyaxisDropDown.Items{2};            
            % Load example
            app.UIAxes3.cla;
            showExample(app.UIAxes3,app.d);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('.'));
            DOMAIN = app.domainDropDown.Value; %npoly dropwave shubert4
            setDomain(app, DOMAIN);
            ALGORITHM = 'grid';
            rmpath('grid'); rmpath('voronoi'); addpath(ALGORITHM);
            % Load configuration 
            app.p = defaultParamSet(4);
            app.nogenerationsEditField.Value = app.p.nGens;
            app.iteration = 1;
            
        end

        % Button pushed function: InitRestartButton
        function InitRestartButtonPushed(app, event)
            if isempty(app.constraints)
                sobSequence         = scramble(sobolset(app.d.dof,'Skip',1e3),'MatousekAffineOwen');
                sobPoint            = 1;
                initSamples         = range(app.d.ranges).*sobSequence(sobPoint:(sobPoint+app.p.numInitSamples)-1,:)+app.d.ranges(1);
            else
                % Get seeds
                initSamples = [];
                for it1=1:size(app.constraints,1)
                    for it2=1:size(app.constraints,2)
                        initSamples = [initSamples; app.constraints{it1,it2}.members];
                    end
                end                
            end
            [fitness, values]   = app.d.fitfun(initSamples);

            obsMap              = createMap(app.d.dof, app.p);
            [replaced, replacement, features] = nicheCompete(initSamples, fitness, values, obsMap, app.d, app.p);
            obsMap = updateMap(replaced,replacement,obsMap,fitness,initSamples,features);            
            [app.map, ~, ~, ~, allMaps] = illuminate(@(x) objective(x,app.d.fitfun,app.constraints,app.d.penaltyWeight),obsMap,app.p,app.d);

            % Show data            
            app.UIAxes.cla;
            viewMap(app.map,app.d,app.UIAxes);% title('Fitness'); caxis(d.fitnessRange);            
        end

        % Value changed function: nogenerationsEditField
        function nogenerationsEditFieldValueChanged(app, event)
            value = app.nogenerationsEditField.Value;
            app.p.nGens = value;
            app.nogenerationsEditField.Value = value;
        end

        % Value changed function: domainDropDown
        function domainDropDownValueChanged(app, event)
            value = app.domainDropDown.Value;
            setDomain(app, value);
        end

        % Value changed function: featurexaxisDropDown
        function featurexaxisDropDownValueChanged(app, event)
            value = app.featurexaxisDropDown.Value;
            col = find(strcmp(value,app.d.featureLabels));
            app.d.selectedFeatures(1) = col;
        end

        % Value changed function: featureyaxisDropDown
        function featureyaxisDropDownValueChanged(app, event)
            value = app.featureyaxisDropDown.Value;
            col = find(strcmp(value,app.d.featureLabels));
            app.d.selectedFeatures(2) = col;            
        end

        % Button pushed function: RedrawButton
        function RedrawButtonPushed(app, event)
            app.UIAxes.cla;
            viewMap(app.map,app.d,app.UIAxes);% title('Fitness'); caxis(d.fitnessRange);
        end

        % Button pushed function: GeneticPrototypingButton
        function GeneticPrototypingButtonPushed(app, event)
            app.classification = extractClasses(app.map.genes);
            % Show classes in similarity space
            viewClasses(app.map.genes,app.classification,app.UIAxes2);
            % Show prototypes
            % Row and col placement
            numRows = ceil( size(app.classification.protoX,1).^(2/3) );
            for i=1:size(app.classification.protoX,1)
                placement(i,:) = [mod(i-1,numRows) floor((i-1)/numRows)]*app.d.spacer;
            end
            app.UIAxes5_3.cla;
            showExample(app.UIAxes5_3,app.d,app.classification.protoX,placement);
            axis(app.UIAxes5_3, 'equal');       
            app.UIAxes5.cla;
            showExample(app.UIAxes5,app.d,app.classification.protoX,placement);
            axis(app.UIAxes5, 'equal');                   
        end

        % Value changed function: ArchiveDropDown
        function ArchiveDropDownValueChanged(app, event)
            value = app.ArchiveDropDown.Value;
            ALGORITHM = value;
            rmpath('grid'); rmpath('voronoi'); addpath(ALGORITHM);       
            nGens = app.p.nGens;
            app.p = defaultParamSet(4);
            app.p.nGens = nGens;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %% Workarounds for UIAxes not having callbacks..            
            pos = event.Source.CurrentPoint;
            
            %  Click on map and show solution
            if ~isempty(app.map) && strcmp(app.TabGroup.SelectedTab.Title,'Illumination')
            figPos = app.UIAxes.InnerPosition;
            if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                    % Get position of mouse click relative to inner position (axis, not figure!)
                    relPos = app.UIAxes.CurrentPoint(1,1:2);
                    distances = pdist2(relPos,app.map.features(:,app.d.selectedFeatures));
                    [~,closest] = min(distances);
                    app.UIAxes3_2.cla;
                    showExample(app.UIAxes3_2,app.d,app.map.genes(closest,:));
                end
            end
            end
            
            %  Click on prototypes and select
            if ~isempty(app.classification) && strcmp(app.TabGroup.SelectedTab.Title,'Selection')
            figPos = app.UIAxes5.InnerPosition;
            if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                    relPos = app.UIAxes5.CurrentPoint(1,1:2);
                    polyAxes = app.UIAxes5.Children;
                    polys = []; numVerts = 0;
                    for i=1:length(polyAxes)
                        polys = [polys;polyAxes(i).Shape.Vertices];
                        numVerts(i+1) = numVerts(i) + size(polyAxes(i).Shape.Vertices,1);
                    end
                    numVerts(1) = [];
                    distances = pdist2(relPos,polys);
                    [~,closest] = min(distances);
                    closestPrototypes = closest<=numVerts; closestPrototypes = fliplr(closestPrototypes);
                    closestPrototypes = find(closestPrototypes);
                    app.selectedPrototypes(end+1) = closestPrototypes(end);
                    % Row and col placement
                    for i=1:size(app.classification.protoX(app.selectedPrototypes,:),1)
                        placement(i,:) = [(i-1)*app.d.spacer 0];
                    end
                    showExample(app.UIAxes5_2,app.d,app.classification.protoX(app.selectedPrototypes,:),placement);
                    axis(app.UIAxes5_2,'equal');
                end
            end
            end
            
            %  Click on prototypes and deselect
            if ~isempty(app.selectedPrototypes) && strcmp(app.TabGroup.SelectedTab.Title,'Selection')
            figPos = app.UIAxes5_2.InnerPosition;
            if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                    relPos = app.UIAxes5_2.CurrentPoint(1,1:2);
                    polyAxes = app.UIAxes5_2.Children;
                    polys = []; numVerts = 0;
                    for i=1:length(polyAxes)
                        polys = [polys;polyAxes(i).Shape.Vertices];
                        numVerts(i+1) = numVerts(i) + size(polyAxes(i).Shape.Vertices,1);
                    end
                    numVerts(1) = [];
                    distances = pdist2(relPos,polys);
                    [~,closest] = min(distances);
                    closestPrototypes = closest<=numVerts; closestPrototypes = fliplr(closestPrototypes);
                    closestPrototypes = find(closestPrototypes);
                    app.selectedPrototypes(closestPrototypes(end)) = [];
                    % Row and col placement
                    if ~isempty(app.selectedPrototypes)
                        for i=1:size(app.classification.protoX(app.selectedPrototypes,:),1)
                            placement(i,:) = [(i-1)*app.d.spacer 0];
                        end
                        showExample(app.UIAxes5_2,app.d,app.classification.protoX(app.selectedPrototypes,:),placement);
                        axis(app.UIAxes5_2,'equal');
                    else
                        cla(app.UIAxes5_2);
                    end
                end
            end
            end
        end

        % Button pushed function: AddSelectionButton
        function AddSelectionButtonPushed(app, event)
            %TODO
            % 1. Create UDHM based on selected prototypes
            app.constraints{app.iteration} = setConstraints(app.classification,app.selectedPrototypes,[],'class');
            % 2. Set seeds to selected prototypes
            
        end

        % Value changed function: IterationListBox
        function IterationListBoxValueChanged(app, event)
            value = app.IterationListBox.Value;
            app.iteration = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DomainTab = uitab(app.TabGroup);
            app.DomainTab.Title = 'Domain';
            app.UIAxes3 = uiaxes(app.DomainTab);
            title(app.UIAxes3, 'Example Solution')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.DomainConfigurationPanel = uipanel(app.DomainTab);
            app.DomainConfigurationPanel.Title = 'Domain Configuration';
            app.domainDropDownLabel = uilabel(app.DomainConfigurationPanel);
            app.domainDropDownLabel.Text = 'domain';
            app.domainDropDown = uidropdown(app.DomainConfigurationPanel);
            app.domainDropDown.Items = {'dropwave', 'shubert4', 'npoly'};
            app.domainDropDown.ValueChangedFcn = createCallbackFcn(app, @domainDropDownValueChanged, true);
            app.domainDropDown.Value = 'npoly';
            app.loadeddomainLabel = uilabel(app.DomainConfigurationPanel);
            app.loadeddomainLabel.Text = 'loaded domain: ';
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.ModelConfigurationPanel = uipanel(app.TrainingTab);
            app.ModelConfigurationPanel.Title = 'Model Configuration';
            app.IlluminationTab = uitab(app.TabGroup);
            app.IlluminationTab.Title = 'Illumination';
            app.QDConfigurationPanel = uipanel(app.IlluminationTab);
            app.QDConfigurationPanel.Title = 'QD Configuration';
            app.ArchiveDropDownLabel = uilabel(app.QDConfigurationPanel);
            app.ArchiveDropDownLabel.Text = 'Archive';
            app.ArchiveDropDown = uidropdown(app.QDConfigurationPanel);
            app.ArchiveDropDown.Items = {'voronoi', 'grid', ''};
            app.ArchiveDropDown.ValueChangedFcn = createCallbackFcn(app, @ArchiveDropDownValueChanged, true);
            app.ArchiveDropDown.Value = 'grid';
            app.nogenerationsEditFieldLabel = uilabel(app.QDConfigurationPanel);
            app.nogenerationsEditFieldLabel.Text = 'no. generations';
            app.nogenerationsEditField = uieditfield(app.QDConfigurationPanel, 'numeric');
            app.nogenerationsEditField.Limits = [1 100000];
            app.nogenerationsEditField.RoundFractionalValues = 'on';
            app.nogenerationsEditField.ValueChangedFcn = createCallbackFcn(app, @nogenerationsEditFieldValueChanged, true);
            app.nogenerationsEditField.Value = 100;
            app.featurexaxisDropDownLabel = uilabel(app.QDConfigurationPanel);
            app.featurexaxisDropDownLabel.Text = 'feature x axis';
            app.featurexaxisDropDown = uidropdown(app.QDConfigurationPanel);
            app.featurexaxisDropDown.Items = {'default'};
            app.featurexaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featurexaxisDropDownValueChanged, true);
            app.featurexaxisDropDown.Value = 'default';
            app.featureyaxisDropDownLabel = uilabel(app.QDConfigurationPanel);
            app.featureyaxisDropDownLabel.Text = 'feature y axis';
            app.featureyaxisDropDown = uidropdown(app.QDConfigurationPanel);
            app.featureyaxisDropDown.Items = {'default'};
            app.featureyaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featureyaxisDropDownValueChanged, true);
            app.featureyaxisDropDown.Value = 'default';
            app.UserSelectionPressureSliderLabel = uilabel(app.QDConfigurationPanel);
            app.UserSelectionPressureSliderLabel.Text = {'User'; ' Selection'; ' Pressure'};
            app.UserSelectionPressureSlider = uislider(app.QDConfigurationPanel);
            app.UserSelectionPressureSlider.MajorTicks = [0 25 50 75 100];
            app.UserSelectionPressureSlider.MajorTickLabels = {'0%', '25%', '50%', '75%', '100%'};
            app.QDcommandsPanel = uipanel(app.IlluminationTab);
            app.QDcommandsPanel.Title = 'QD commands';
            app.InitRestartButton = uibutton(app.QDcommandsPanel, 'push');
            app.InitRestartButton.ButtonPushedFcn = createCallbackFcn(app, @InitRestartButtonPushed, true);
            app.InitRestartButton.Text = 'Init/Restart';
            app.RedrawButton = uibutton(app.QDcommandsPanel, 'push');
            app.RedrawButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawButtonPushed, true);
            app.RedrawButton.Text = 'Redraw';
            app.UIAxes = uiaxes(app.IlluminationTab);
            title(app.UIAxes, 'Phenotypic Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes3_2 = uiaxes(app.IlluminationTab);
            title(app.UIAxes3_2, 'Viewed Solution')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.IterationListBoxLabel = uilabel(app.IlluminationTab);
            app.IterationListBoxLabel.Text = 'Iteration';
            app.IterationListBox = uilistbox(app.IlluminationTab);
            app.IterationListBox.Items = {'1'};
            app.IterationListBox.ValueChangedFcn = createCallbackFcn(app, @IterationListBoxValueChanged, true);
            app.IterationListBox.Value = '1';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisPanel = uipanel(app.AnalysisTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.GeneticPrototypingButton = uibutton(app.AnalysisPanel, 'push');
            app.GeneticPrototypingButton.ButtonPushedFcn = createCallbackFcn(app, @GeneticPrototypingButtonPushed, true);
            app.GeneticPrototypingButton.Text = 'Genetic Prototyping';
            app.UIAxes2 = uiaxes(app.AnalysisTab);
            title(app.UIAxes2, 'Genotypic Space')
            xlabel(app.UIAxes2, '1')
            ylabel(app.UIAxes2, '2')
            app.UIAxes2.XTick = [0 0.5 1];
            app.UIAxes2.YTick = [0 0.5 1];
            app.UIAxes5_3 = uiaxes(app.AnalysisTab);
            title(app.UIAxes5_3, 'Prototypes')
            xlabel(app.UIAxes5_3, 'X')
            ylabel(app.UIAxes5_3, 'Y')
            app.UIAxes5_3.XLim = [0 2.5];
            app.SelectionTab = uitab(app.TabGroup);
            app.SelectionTab.Title = 'Selection';
            app.UserSelectionPanel = uipanel(app.SelectionTab);
            app.UserSelectionPanel.Title = 'User Selection';
            app.AddSelectionButton = uibutton(app.UserSelectionPanel, 'push');
            app.AddSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddSelectionButtonPushed, true);
            app.AddSelectionButton.Text = 'Add Selection';
            app.RemoveButton = uibutton(app.UserSelectionPanel, 'push');
            app.RemoveButton.Text = 'Remove';
            app.ShowUDHMButton = uibutton(app.UserSelectionPanel, 'push');
            app.ShowUDHMButton.Text = 'Show UDHM';
            app.UIAxes6_2 = uiaxes(app.SelectionTab);
            title(app.UIAxes6_2, 'Selection Model (G)')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            app.UIAxes5 = uiaxes(app.SelectionTab);
            title(app.UIAxes5, 'Prototypes')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes5.XLim = [0 2.5];
            app.UIAxes5_2 = uiaxes(app.SelectionTab);
            title(app.UIAxes5_2, 'Selection')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            app.UIAxes5_2.XLim = [-1 20];
            app.UIAxes5_2.YLim = [-2 2];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demoGUItabs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end